classdef Spectra_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DonorListBoxLabel              matlab.ui.control.Label
        DonorListBox                   matlab.ui.control.ListBox
        AcceptorListBoxLabel           matlab.ui.control.Label
        AcceptorListBox                matlab.ui.control.ListBox
        DonorExnmEditFieldLabel        matlab.ui.control.Label
        DonorExnmEditField             matlab.ui.control.NumericEditField
        AcceptorExnmEditFieldLabel     matlab.ui.control.Label
        AcceptorExnmEditField          matlab.ui.control.NumericEditField
        DonorEmCBWnmEditFieldLabel     matlab.ui.control.Label
        DonorEmCBWnmEditField          matlab.ui.control.EditField
        AcceptorEmCBWnmEditFieldLabel  matlab.ui.control.Label
        AcceptorEmCBWnmEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        Files % Description
        Spectra % Description
    end

    methods (Access = private)
    
        function names = GetNames(app,file)
            fid=fopen(file,'r');
            l = fgetl(fid); %read header
            l = strsplit(l,',');
            if isempty(l{end}),l(end)=[];end
            names = l(2:end);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Load_Spectra(app)
            app.Files=dir('*\*.csv');
            app.Spectra={};
            for ct = 1:length(app.Files)
                n=GetNames(app,fullfile(app.Files(ct).folder,app.Files(ct).name));
                app.Spectra(end+1:end+length(n),1)=n;
                app.Spectra(end-length(n)+1:end,2)={ct};
            end
            app.DonorListBox.Items=app.Spectra(:,1);
            app.AcceptorListBox.Items=app.Spectra(:,1);
        end

        % Value changed function: DonorListBox
        function DonorListBoxValueChanged(app, event)
            value = app.DonorListBox.Value
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectra')
            xlabel(app.UIAxes, 'Lambda(nm)')
            ylabel(app.UIAxes, 'Normalized Intensity')
            app.DonorListBoxLabel = uilabel(app.UIFigure);
            app.DonorListBoxLabel.Text = 'Donor';
            app.DonorListBox = uilistbox(app.UIFigure);
            app.DonorListBox.ValueChangedFcn = createCallbackFcn(app, @DonorListBoxValueChanged, true);
            app.AcceptorListBoxLabel = uilabel(app.UIFigure);
            app.AcceptorListBoxLabel.Text = 'Acceptor';
            app.AcceptorListBox = uilistbox(app.UIFigure);
            app.DonorExnmEditFieldLabel = uilabel(app.UIFigure);
            app.DonorExnmEditFieldLabel.Text = 'Donor Ex (nm)';
            app.DonorExnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DonorExnmEditField.Value = 440;
            app.AcceptorExnmEditFieldLabel = uilabel(app.UIFigure);
            app.AcceptorExnmEditFieldLabel.Text = 'Acceptor Ex (nm)';
            app.AcceptorExnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcceptorExnmEditField.Value = 500;
            app.DonorEmCBWnmEditFieldLabel = uilabel(app.UIFigure);
            app.DonorEmCBWnmEditFieldLabel.Text = 'Donor Em C/BW (nm)';
            app.DonorEmCBWnmEditField = uieditfield(app.UIFigure, 'text');
            app.DonorEmCBWnmEditField.Value = '440/30';
            app.AcceptorEmCBWnmEditFieldLabel = uilabel(app.UIFigure);
            app.AcceptorEmCBWnmEditFieldLabel.Text = 'Acceptor Em C/BW (nm)';
            app.AcceptorEmCBWnmEditField = uieditfield(app.UIFigure, 'text');
            app.AcceptorEmCBWnmEditField.Value = '550/30';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Spectra_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Load_Spectra)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end